classdef Duration < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        DoneSelectingParseAllTheLengthsButton  matlab.ui.control.Button
        AddasetoffilesButton       matlab.ui.control.Button
        FilestobeOutputLabel       matlab.ui.control.Label
        FilestobeOutputTextArea    matlab.ui.control.TextArea
        SelectfilestoprocessLabel  matlab.ui.control.Label
        Image                      matlab.ui.control.Image
    end

    
    properties (Access = private)
        Sets={} % cell to compile list of all selected filesets 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AddasetoffilesButton
        function AddasetoffilesButtonPushed(app, event)
            [files,pathname,~]=uigetfile('*.czi',"Choose files from a single user",'MultiSelect','on');
            
            if ~iscell(files)
                files={files};
            end
            
            if files{1}~=0
                cd(pathname)
                [outfile,outpath]=uiputfile('.csv',"Where to output the data");
                Fileset.files=files;
                Fileset.pathname=pathname;
                Fileset.outfile=outfile;
                Fileset.outpath=outpath;
                app.Sets{end+1}=Fileset;
                
                listtext=cellfun(@(x) x.outfile, app.Sets,'UniformOutput',false);
                app.FilestobeOutputTextArea.Value=listtext;
            end
           
            figure(app.UIFigure)
        end

        % Button pushed function: 
        % DoneSelectingParseAllTheLengthsButton
        function DoneSelectingParseAllTheLengthsButtonPushed(app, event)
            
            header={'File Name','Acquisition Date','Acquisition Time','Duration (ms)','Duration (min)'};
            app.Image.Visible = 'on';
            drawnow
            
            for set=1:length(app.Sets)
                outMat={};
                %mins=[];
                Fileset=app.Sets{set};
                disp(Fileset)
                files=Fileset.files;
                pathname=Fileset.pathname;
                outfile=Fileset.outfile;
                outpath=Fileset.outpath;
                               
                %cycle through all hand-chosen files
                for f=1:length(files)
                    try
                    filename=fullfile(pathname,files{f});
                    app.SelectfilestoprocessLabel.Text=['Working On: ' files{f}];
                    app.SelectfilestoprocessLabel.BackgroundColor = [1 0.67 0.67];
                    drawnow
                    clear acqduration date reader globalmetadata metadataKeys
                    reader=bfGetReader(filename);
                    globalmetadata=reader.getGlobalMetadata;
                    
                    metadataKeys = globalmetadata.keySet().iterator();
                    for i=1:globalmetadata.size()
                        key = metadataKeys.nextElement();
                        if contains(key,'AcquisitionDuration')
                            acqduration=globalmetadata.get(key);
                        elseif contains(key,'AcquisitionDateAndTime')
                            date=globalmetadata.get(key);
                        end
                    end
                                        
                    if exist('acqduration') && exist('date')
                        
                        dateSplit=split(date,'T');
                        daySplit=split(dateSplit{1},'.');
                        timeSplit=split(dateSplit{2},'.');
                        durationSplit=split(acqduration,'.');
                        
                        rawname=files{f};
                        outMat{end+1,1}=rawname(rawname~=',');
                        outMat{end,2}=daySplit{1};
                        outMat{end,3}=timeSplit{1};
                        outMat{end,4}=durationSplit{1};
                        % outMat{end,5}=num2str(mins(end),3);
                        outMat{end,5}=str2num(acqduration)/1000/60;
                        
                        reader.close()
                    end
                    catch ME
                        disp(files)
                        disp(ME.message)
                        outMat{end+1,1}=files{f};
                        outMat{end,2}=NaN;
                        outMat{end,3}=NaN;
                        outMat{end,4}=NaN;
                        % outMat{end,5}=num2str(mins(end),3);
                        outMat{end,5}=NaN;
                    end
                end
               
                outfile=fullfile(outpath, outfile);
                fid2=fopen(outfile,'w');
                
                fprintf(fid2,'%s,%s,%s,%s,%s\n',header{:});
                for j=1:size(outMat,1)
                    fprintf(fid2,'%s,%s,%s,%s,%.2f\n',outMat{j,:});
                end
                
                fprintf(fid2,'\n\n');
                
                total=sum(cell2mat(outMat(:,5)));
                fprintf(fid2,'%s,%6.2f','Total Acquisition Time (hours):',total/60);
                fclose(fid2);     
            end
            
            app.SelectfilestoprocessLabel.Text='Done!';
            app.SelectfilestoprocessLabel.BackgroundColor = [0 1 0];
            app.AddasetoffilesButton.Enable=false;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.DoneSelectingParseAllTheLengthsButton = uibutton(app.UIFigure, 'push');
            app.DoneSelectingParseAllTheLengthsButton.ButtonPushedFcn = createCallbackFcn(app, @DoneSelectingParseAllTheLengthsButtonPushed, true);
            app.DoneSelectingParseAllTheLengthsButton.Text = {'Done Selecting?'; ''; 'Parse All The Lengths!'};
            app.AddasetoffilesButton = uibutton(app.UIFigure, 'push');
            app.AddasetoffilesButton.ButtonPushedFcn = createCallbackFcn(app, @AddasetoffilesButtonPushed, true);
            app.AddasetoffilesButton.Text = 'Add a set of files';
            app.FilestobeOutputLabel = uilabel(app.UIFigure);
            app.FilestobeOutputLabel.Text = 'Files to be Output';
            app.FilestobeOutputTextArea = uitextarea(app.UIFigure);
            app.SelectfilestoprocessLabel = uilabel(app.UIFigure);
            app.SelectfilestoprocessLabel.Text = 'Select files to process.';
            app.Image = uiimage(app.UIFigure);
            app.Image.Visible = 'off';
            app.Image.ImageSource = 'ParseAllTheLengths.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Duration

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end